classdef main_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        TabGroup2                      matlab.ui.container.TabGroup
        ManipulatorTab                 matlab.ui.container.Tab
        UITable                        matlab.ui.control.Table
        NumJointsEditField             matlab.ui.control.NumericEditField
        NumJointsEditFieldLabel        matlab.ui.control.Label
        SimulationTab                  matlab.ui.container.Tab
        WalldampingNsmEditField        matlab.ui.control.NumericEditField
        WalldampingNsmEditFieldLabel   matlab.ui.control.Label
        WallstiffnessNmEditField       matlab.ui.control.NumericEditField
        WallstiffnessNmEditFieldLabel  matlab.ui.control.Label
        xwallpositionmEditField        matlab.ui.control.NumericEditField
        xwallpositionmEditFieldLabel   matlab.ui.control.Label
        EnvironmentCheckBox            matlab.ui.control.CheckBox
        ExternalInputDropDown          matlab.ui.control.DropDown
        ExternalInputDropDownLabel     matlab.ui.control.Label
        UITable2                       matlab.ui.control.Table
        TimeStepdtEditField            matlab.ui.control.NumericEditField
        TimeStepdtEditFieldLabel       matlab.ui.control.Label
        DurationsEditField             matlab.ui.control.NumericEditField
        DurationsEditFieldLabel        matlab.ui.control.Label
        ControlTab                     matlab.ui.container.Tab
        TabGroup3                      matlab.ui.container.TabGroup
        SharedTab                      matlab.ui.container.Tab
        UITable4                       matlab.ui.control.Table
        TrajectoryDropDown             matlab.ui.control.DropDown
        TrajectoryDropDownLabel        matlab.ui.control.Label
        ModeDropDown                   matlab.ui.control.DropDown
        ModeDropDownLabel              matlab.ui.control.Label
        DynamicsTab                    matlab.ui.container.Tab
        ComplianceTab                  matlab.ui.container.Tab
        UITable5                       matlab.ui.control.Table
        GravityCompensationCheckBox    matlab.ui.control.CheckBox
        ImpedanceTab                   matlab.ui.container.Tab
        IncludeCoriolisCentrifugalCheckBox  matlab.ui.control.CheckBox
        IncludeGravityCompensationCheckBox  matlab.ui.control.CheckBox
        UseInverseDynamicsCheckBox     matlab.ui.control.CheckBox
        UITable6                       matlab.ui.control.Table
        RUNButton                      matlab.ui.control.Button
        RESETButton                    matlab.ui.control.Button
        CenterPanel                    matlab.ui.container.Panel
        UIAxes8                        matlab.ui.control.UIAxes
        RightPanel                     matlab.ui.container.Panel
        TabGroup                       matlab.ui.container.TabGroup
        JointSpaceTab                  matlab.ui.container.Tab
        UIAxes3                        matlab.ui.control.UIAxes
        UIAxes2                        matlab.ui.control.UIAxes
        UIAxes                         matlab.ui.control.UIAxes
        TaskSpaceTab                   matlab.ui.container.Tab
        UIAxes5                        matlab.ui.control.UIAxes
        UIAxes4                        matlab.ui.control.UIAxes
        ForcesTab                      matlab.ui.container.Tab
        UIAxes7                        matlab.ui.control.UIAxes
        UIAxes6                        matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    methods (Access = private)
        
        function input = buildSimulationInput(app)
            input = create_sim_input();

            data = app.UITable.Data;
            N = size(data,1);
        
            input.robot.n_joints = N;
            input.robot.joint_types = string(data(:,1));
        
            % Safe scalar extraction
            input.robot.a = safe_copy(data,2,N);
            input.robot.alpha = safe_copy(data,3,N);
            input.robot.d = safe_copy(data,4,N);
            input.robot.theta = safe_copy(data,5,N);
        
            input.robot.masses = safe_copy(data,6,N);
        
            % COM (Nx3)
            comx = safe_copy(data,7,N);
            comy = safe_copy(data,8,N);
            comz = safe_copy(data,9,N);
            input.robot.COM = [comx, comy, comz];
        
            % Inertia (Nx3)
            Ix = safe_copy(data,10,N);
            Iy = safe_copy(data,11,N);
            Iz = safe_copy(data,12,N);
            input.robot.inertia = [Ix, Iy, Iz];

            data2 = app.UITable2.Data;
        
            input.sim.dt = app.TimeStepdtEditField.Value;
            input.sim.t_final = app.DurationsEditField.Value;
        
            input.sim.q0 = safe_copy(data2,1,N);
            input.sim.qd0 = safe_copy(data2,2,N);
        
            input.sim.external_tau = safe_copy(data2,3,N);
        
            input.sim.external_amplitude = safe_copy(data2,4,N);
            input.sim.external_frequency = safe_copy(data2,5,N);
            input.sim.external_input = char(app.ExternalInputDropDown.Value);
        
            input.sim.environment_enabled = app.EnvironmentCheckBox.Value;
        
            input.sim.surface_pose = app.xwallpositionmEditField.Value;
            input.sim.stiffness = app.WallstiffnessNmEditField.Value;
            input.sim.damping = app.WalldampingNsmEditField.Value;

            input.control.mode = char(app.ModeDropDown.Value);
        
            data5 = app.UITable5.Data;
            input.control.kp = safe_copy(data5,1,N);
            input.control.kd = safe_copy(data5,2,N);
        
            input.control.gravity_comp = app.GravityCompensationCheckBox.Value;
        
            data6 = app.UITable6.Data;
            input.control.Md = cell2mat(data6(1,1));
            input.control.Bd = cell2mat(data6(1,2));
            input.control.Kd = cell2mat(data6(1,3));
        
            input.control.use_inverse_dynamics = app.UseInverseDynamicsCheckBox.Value;
            input.control.include_gravity = app.IncludeGravityCompensationCheckBox.Value;
            input.control.include_coriolis = app.IncludeCoriolisCentrifugalCheckBox.Value;

            input.trajectory.type = char(app.TrajectoryDropDown.Value);
        
            data4 = app.UITable4.Data;
        
            if isempty(data4) || size(data4,2) < 12
                error('Trajectory table (UITable4) is not initialized correctly.');
            end
        
            M = size(data4,1);

            x = safe_copy(data4,1,M);
            y = safe_copy(data4,2,M);
            z = safe_copy(data4,3,M);
            
            xd = safe_copy(data4,4,M);
            yd = safe_copy(data4,5,M);
            zd = safe_copy(data4,6,M);
            
            xdd = safe_copy(data4,7,M);
            ydd = safe_copy(data4,8,M);
            zdd = safe_copy(data4,9,M);
            
            amp = safe_copy(data4,10,M);
            freq = safe_copy(data4,11,M);
            step_time = safe_copy(data4,12,M);
            
            input.trajectory.amplitude = amp;
            input.trajectory.frequency = freq;
            input.trajectory.step_time = step_time;
            
            trajType = char(app.TrajectoryDropDown.Value);
            input.trajectory.type = trajType;
            
            switch trajType
                case 'Constant'
                    input.trajectory.x_des = [x, y, z];
                    input.trajectory.xd_des = [xd, yd, zd];
                    input.trajectory.xdd_des = [xdd, ydd, zdd];
            
                case 'Sinusoidal'
                    t = (0:input.sim.dt:input.sim.t_final)';
                    nT = length(t);
          
                    x0 = x(1);
                    y0 = y(1);
                    z0 = z(1);
            
                    A = amp(1);
                    f = freq(1);
                    w = 2*pi*f;
            
                    input.trajectory.x_des = zeros(nT,3);
                    input.trajectory.xd_des = zeros(nT,3);
                    input.trajectory.xdd_des = zeros(nT,3);
            
                    input.trajectory.x_des(:,1) = x0 + A*sin(w*t);
                    input.trajectory.x_des(:,2) = y0;
                    input.trajectory.x_des(:,3) = z0;
            
                    input.trajectory.xd_des(:,1) = A*w*cos(w*t);
                    input.trajectory.xd_des(:,2) = 0;
                    input.trajectory.xd_des(:,3) = 0;
            
                    input.trajectory.xdd_des(:,1) = -A*w^2*sin(w*t);
                    input.trajectory.xdd_des(:,2) = 0;
                    input.trajectory.xdd_des(:,3) = 0;
            end

            val_sim_input(input);
        
        end

        function animateManipulator(app, input, output)
               ax = app.UIAxes8;
               cla(ax);
            
               ax.Color = 'white';
               ax.XColor = 'white';
               ax.YColor = 'white';
               ax.ZColor = 'white';
               ax.GridColor = [0.65 0.65 0.65];
               ax.MinorGridColor = [0.80 0.80 0.80];
               ax.Box = 'on';
        
              N = input.robot.n_joints;
              t = output.time(:);
              q = output.q;
              ee = output.end_effector.position;
        
              playbackSpeed = 1.0;
              fps = 30;
        
              skip = max(1, round(1/(input.sim.dt * fps)));
              frames = 1:skip:length(t);
              if frames(end) ~= length(t)
                  frames(end+1) = length(t);
              end
        
              allJointPos = zeros(numel(frames), N+1, 3);
        
              for k = 1:numel(frames)
                  idx = frames(k);
                  kin = kinematics(q(idx,:)', input.robot);
        
                  allJointPos(k,1,:) = [0 0 0];
        
                  for i = 1:N
                      allJointPos(k,i+1,:) = kin.T_all(1:3,4,i)';
                  end
              end
        
              xyzMin = squeeze(min(allJointPos, [], [1 2]))';
              xyzMax = squeeze(max(allJointPos, [], [1 2]))';
        
              if isfield(output, 'end_effector') && ...
                 isfield(output.end_effector, 'desired_position') && ...
                 ~isempty(output.end_effector.desired_position)
        
                  xd = output.end_effector.desired_position;
                  xyzMin = min(xyzMin, min(xd, [], 1));
                  xyzMax = max(xyzMax, max(xd, [], 1));
              end
        
              center = 0.5 * (xyzMin + xyzMax);
              span = max(xyzMax - xyzMin);
              span = max(span, 1.0);
              pad = 0.15 * span;
              halfRange = 0.5 * span + pad;
        
              hold(ax, 'on');
              grid(ax, 'on');
              axis(ax, 'equal');
              view(ax, [135 25]);
        
              xlim(ax, center(1) + [-halfRange halfRange]);
              ylim(ax, center(2) + [-halfRange halfRange]);
              zlim(ax, center(3) + [-halfRange halfRange]);
        
              xlabel(ax, 'X');
              ylabel(ax, 'Y');
              zlabel(ax, 'Z');
              title(ax, 'Manipulator Motion');
              ax.Title.Color = 'white';
              ax.XLabel.Color = 'white';
              ax.YLabel.Color = 'white';
              ax.ZLabel.Color = 'white';

              if isfield(input.sim, 'environment_enabled') && input.sim.environment_enabled
                 xWall = input.sim.surface_pose(1);
            
                 yLimits = ylim(ax);
                 zLimits = zlim(ax);
            
                 [Ywall, Zwall] = meshgrid( ...
                     linspace(yLimits(1), yLimits(2), 2), ...
                     linspace(zLimits(1), zLimits(2), 2));
            
                 Xwall = xWall * ones(size(Ywall));
            
                 surf(ax, Xwall, Ywall, Zwall, ...
                    'FaceColor', [1.0 0.2 0.2], ...
                    'FaceAlpha', 0.15, ...
                    'EdgeColor', [0.7 0.0 0.0], ...
                    'EdgeAlpha', 0.35, ...
                    'Tag', 'xWallSurf');
                
                text(ax, xWall, yLimits(2), zLimits(2), 'X-wall', ...
                    'Color', [0.6 0 0], ...
                    'FontWeight', 'bold', ...
                    'Tag', 'xWallText');
              end
        
              hLinks = gobjects(N,1);
              linkColors = lines(N);
        
              for i = 1:N
                  hLinks(i) = plot3(ax, [0 0], [0 0], [0 0], ...
                      'Color', linkColors(i,:), ...
                      'LineStyle', '-', ...
                      'LineWidth', 4);
              end
        
              hJoints = scatter3(ax, 0, 0, 0, 50, 'b', 'filled');
              hEE = scatter3(ax, 0, 0, 0, 80, 'r', 'filled');
              hTrail = plot3(ax, nan, nan, nan, 'r-', 'LineWidth', 1.2);
        
              if isfield(output, 'end_effector') && ...
                 isfield(output.end_effector, 'desired_position') && ...
                 ~isempty(output.end_effector.desired_position)
        
                  xd = output.end_effector.desired_position;
                  plot3(ax, xd(:,1), xd(:,2), xd(:,3), 'r--', 'LineWidth', 1.0);
              end
        
              for k = 1:numel(frames)
                  idx = frames(k);
        
                  jointPos = squeeze(allJointPos(k,:,:))';
        
                  for i = 1:N
                      set(hLinks(i), ...
                          'XData', jointPos(1,i:i+1), ...
                          'YData', jointPos(2,i:i+1), ...
                          'ZData', jointPos(3,i:i+1));
                  end
        
                  set(hJoints, ...
                      'XData', jointPos(1,:), ...
                      'YData', jointPos(2,:), ...
                      'ZData', jointPos(3,:));
        
                  set(hEE, ...
                      'XData', jointPos(1,end), ...
                      'YData', jointPos(2,end), ...
                      'ZData', jointPos(3,end));
        
                  set(hTrail, ...
                      'XData', ee(1:idx,1), ...
                      'YData', ee(1:idx,2), ...
                      'ZData', ee(1:idx,3));
        
                  drawnow;
        
                  if k < numel(frames)
                      dtFrame = t(frames(k+1)) - t(frames(k));
                      pause(dtFrame / playbackSpeed);
                  end
              end
        end

        function dataOut = resizeTableData(app, dataIn, N, numCols, defaultRow)
            if isempty(dataIn)
                oldRows = 0;
                dataOut = cell(0, numCols);
            else
                oldRows = size(dataIn, 1);
                oldCols = size(dataIn, 2);
        
                dataOut = cell(oldRows, numCols);
        
                colsToCopy = min(oldCols, numCols);
                dataOut(:,1:colsToCopy) = dataIn(:,1:colsToCopy);
        
                if oldCols < numCols
                    for r = 1:oldRows
                        dataOut(r, oldCols+1:numCols) = defaultRow(oldCols+1:numCols);
                    end
                end
            end
        
            if oldRows < N
                for r = oldRows+1:N
                    dataOut(r,:) = defaultRow;
                end
            elseif oldRows > N
                dataOut = dataOut(1:N,:);
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.NumJointsEditField.Value = 3;
            NumJointsEditFieldValueChanged(app, []);
            app.UITable4.Data = num2cell(zeros(1,12));
            %addpath(genpath('src/interfaces'));
            %addpath(genpath('src/modules'));
            %addpath(genpath('src/core'));
            %addpath(genpath('src/utils'));

            projectRoot = fileparts(fileparts(fileparts(mfilename('fullpath'))));

            run(fullfile(projectRoot, 'rvctools', 'startup_rvc.m'));
        
            addpath(genpath(fullfile(projectRoot, 'src', 'interfaces')));
            addpath(genpath(fullfile(projectRoot, 'src', 'modules')));
            addpath(genpath(fullfile(projectRoot, 'src', 'core')));
            addpath(genpath(fullfile(projectRoot, 'src', 'utils')));

            app.UITable.Data = {
                  'R', 0.60, 0, 0, 0, 2.0, 0.30, 0, 0, 0.02, 0.02, 0.01;
                  'R', 0.40, 0, 0, 0, 1.5, 0.20, 0, 0, 0.01, 0.01, 0.005;
                  'R', 0.25, 0, 0, 0, 1.0, 0.125, 0, 0, 0.005, 0.005, 0.002
            };

            app.UITable2.Data = {
                   pi/6, 0, 0.4, 0.2, 0.5;
                  -pi/4, 0, 0.2, 0.1, 0.5;
                   pi/6, 0, 0.1, 0.05,0.5
            };
            app.xwallpositionmEditField.Value = 0.85;
            app.WallstiffnessNmEditField.Value = 500;
            app.WalldampingNsmEditField.Value = 20;

            app.TimeStepdtEditField.Value = 0.01;
            app.DurationsEditField.Value = 5.0;
            app.ExternalInputDropDown.Value = 'Constant Torque';
            app.EnvironmentCheckBox.Value = true;

            app.UITable4.Data = {
                0.95, 0.25, 0, 0, 0, 0, 0, 0, 0, 0.10, 0.5, 1.0
            };

            app.UITable5.Data = {
                60, 12;
                50, 10;
                30, 8
            };

            app.GravityCompensationCheckBox.Value = true;

            app.UITable6.Data = {
                1.0, 20, 120;
            };
            
            app.UseInverseDynamicsCheckBox.Value = true;
            app.IncludeGravityCompensationCheckBox.Value = true;
            app.IncludeCoriolisCentrifugalCheckBox.Value = true;
        end

        % Value changed function: NumJointsEditField
        function NumJointsEditFieldValueChanged(app, event)
            N = round(app.NumJointsEditField.Value);
            N = max(N, 1);
            app.NumJointsEditField.Value = N;
        
            rowNames = arrayfun(@(i) sprintf('Joint %d', i), 1:N, 'UniformOutput', false);
        
            defaultManipulatorRow = {'R', 0.25, 0, 0, 0, 1.0, 0.125, 0, 0, ...
                                     0.005, 0.005, 0.002};
        
            app.UITable.Data = resizeTableData(app, app.UITable.Data, N, 12, defaultManipulatorRow);
            app.UITable.RowName = rowNames;
            app.UITable.ColumnFormat = [{'char'}, repmat({'numeric'}, 1, 13)];
            app.UITable.ColumnEditable = true(1,12);
        
            defaultSimulationRow = {0, 0, 0, 0, 0.5};
        
            app.UITable2.Data = resizeTableData(app, app.UITable2.Data, N, 5, defaultSimulationRow);
            app.UITable2.RowName = rowNames;
            app.UITable2.ColumnFormat = repmat({'numeric'}, 1, 5);
            app.UITable2.ColumnEditable = true(1,5);

            defaultComplianceRow = {30, 8};
        
            app.UITable5.Data = resizeTableData(app, app.UITable5.Data, N, 2, defaultComplianceRow);
            app.UITable5.RowName = rowNames;
            app.UITable5.ColumnFormat = repmat({'numeric'}, 1, 2);
            app.UITable5.ColumnEditable = true(1,2);

            app.UITable6.Data = {1.0, 20, 120};
            app.UITable6.RowName = {};
            app.UITable6.ColumnFormat = repmat({'numeric'}, 1, 3);
            app.UITable6.ColumnEditable = true(1,3);
        
            app.UITable4.ColumnEditable = true(1,12);
         
        end

        % Button pushed function: RESETButton
        function RESETButtonPushed(app, event)
            cla(app.UIAxes);
            cla(app.UIAxes2);
            cla(app.UIAxes3);
            cla(app.UIAxes4);
            cla(app.UIAxes5);
            cla(app.UIAxes6);
            cla(app.UIAxes7);
            cla(app.UIAxes8);
            app.NumJointsEditField.Value = 3;

            NumJointsEditFieldValueChanged(app, []);

            app.ModeDropDown.Value = 'Dynamics';
            app.TrajectoryDropDown.Value = 'Constant';
            app.ExternalInputDropDown.Value = 'Constant Torque';

            app.UITable.Data = {
                  'R', 0.60, 0, 0, 0, 2.0, 0.30, 0, 0, 0.02, 0.02, 0.01;
                  'R', 0.40, 0, 0, 0, 1.5, 0.20, 0, 0, 0.01, 0.01, 0.005;
                  'R', 0.25, 0, 0, 0, 1.0, 0.125, 0, 0, 0.005, 0.005, 0.002
            };

            app.UITable2.Data = {
                   pi/6, 0, 0.4, 0.2, 0.5;
                  -pi/4, 0, 0.2, 0.1, 0.5;
                   pi/6, 0, 0.1, 0.05,0.5
            };

            app.xwallpositionmEditField.Value = 0.85;
            app.WallstiffnessNmEditField.Value = 500;
            app.WalldampingNsmEditField.Value = 20;

            app.TimeStepdtEditField.Value = 0.01;
            app.DurationsEditField.Value = 5.0;
            app.EnvironmentCheckBox.Value = true;

            app.UITable4.Data = {
                0.95, 0.25, 0, 0, 0, 0, 0, 0, 0, 0.10, 0.5, 1.0
            };

            app.UITable5.Data = {
                60, 12;
                50, 10;
                30, 8
            };

            app.GravityCompensationCheckBox.Value = true;

            app.UITable6.Data = {1.0, 20, 120};
            app.UseInverseDynamicsCheckBox.Value = true;
            app.IncludeGravityCompensationCheckBox.Value = true;
            app.IncludeCoriolisCentrifugalCheckBox.Value = true;
        end

        % Button pushed function: RUNButton
        function RUNButtonPushed(app, event)
            input = buildSimulationInput(app);
            val_sim_input(input);
            output = run_simulation(input);
            animateManipulator(app, input, output);

            t = output.time(:);

            N = input.robot.n_joints;
            jointLabels = arrayfun(@(i) sprintf('q_%d', i), 1:N, 'UniformOutput', false);
            taskLabels = {'X', 'Y', 'Z'};
            desiredTaskLabels = {'X_d', 'Y_d', 'Z_d'};
            forceLabels = {'F_x', 'F_y', 'F_z'};

            cla(app.UIAxes);
            cla(app.UIAxes2);
            cla(app.UIAxes3);
            cla(app.UIAxes4);
            cla(app.UIAxes5);
            cla(app.UIAxes6);
            cla(app.UIAxes7);
            
             plot(app.UIAxes, t, output.q, 'LineWidth', 1.2);
             legend(app.UIAxes, jointLabels, 'Location', 'best');
             xlabel(app.UIAxes, 'Time (s)');
             ylabel(app.UIAxes, 'q');
             grid(app.UIAxes, 'on');
            
             plot(app.UIAxes2, t, output.qd, 'LineWidth', 1.2);
             legend(app.UIAxes2, jointLabels, 'Location', 'best');
             xlabel(app.UIAxes2, 'Time (s)');
             ylabel(app.UIAxes2, 'qdot');
             grid(app.UIAxes2, 'on');
            
             plot(app.UIAxes3, t, output.qdd, 'LineWidth', 1.2);
             legend(app.UIAxes3, jointLabels, 'Location', 'best');
             xlabel(app.UIAxes3, 'Time (s)');
             ylabel(app.UIAxes3, 'qddot');
             grid(app.UIAxes3, 'on');
            
             if isfield(output, 'end_effector') && isfield(output.end_effector, 'position')
                 plot(app.UIAxes4, t, output.end_effector.position, 'LineWidth', 1.2);
                 legend(app.UIAxes4, taskLabels, 'Location', 'best');
                 xlabel(app.UIAxes4, 'Time (s)');
                 ylabel(app.UIAxes4, 'Position');
                 grid(app.UIAxes4, 'on');
             end
            
             if isfield(output, 'end_effector') && isfield(output.end_effector, 'desired_position')
                 plot(app.UIAxes5, t, output.end_effector.desired_position, '--', 'LineWidth', 1.2);
                 legend(app.UIAxes5, desiredTaskLabels, 'Location', 'best');
                 xlabel(app.UIAxes5, 'Time (s)');
                 ylabel(app.UIAxes5, 'Desired Position');
                 grid(app.UIAxes5, 'on');
             end
            
             if isfield(output, 'tau')
                 plot(app.UIAxes6, t, output.tau, 'LineWidth', 1.2);
                 legend(app.UIAxes6, jointLabels, 'Location', 'best');
                 xlabel(app.UIAxes6, 'Time (s)');
                 ylabel(app.UIAxes6, 'Torque');
                 grid(app.UIAxes6, 'on');
             end
            
             if isfield(output, 'contact_force')
                 plot(app.UIAxes7, t, output.contact_force, 'LineWidth', 1.2);
                 legend(app.UIAxes7, forceLabels, 'Location', 'best');
                 xlabel(app.UIAxes7, 'Time (s)');
                 ylabel(app.UIAxes7, 'Contact Force');
                 grid(app.UIAxes7, 'on');
             end
        
            
        end

        % Value changed function: xwallpositionmEditField
        function xwallpositionmEditFieldValueChanged(app, event)
                xWall = app.xwallpositionmEditField.Value;
    
                hWall = findobj(app.UIAxes8, 'Tag', 'xWallSurf');
                hText = findobj(app.UIAxes8, 'Tag', 'xWallText');
                
                if ~isempty(hWall)
                    hWall.XData = xWall * ones(size(hWall.XData));
                end
                
                if ~isempty(hText)
                    hText.Position(1) = xWall;
                end
            
        end

        % Value changed function: WallstiffnessNmEditField
        function WallstiffnessNmEditFieldValueChanged(app, event)
            value = app.WallstiffnessNmEditField.Value;
            
        end

        % Value changed function: WalldampingNsmEditField
        function WalldampingNsmEditFieldValueChanged(app, event)
            value = app.WalldampingNsmEditField.Value;
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {480, 480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {260, '1x', 253};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.RESETButton = uibutton(app.LeftPanel, 'push');
            app.RESETButton.ButtonPushedFcn = createCallbackFcn(app, @RESETButtonPushed, true);
            app.RESETButton.Text = 'RESET';
            app.RUNButton = uibutton(app.LeftPanel, 'push');
            app.RUNButton.ButtonPushedFcn = createCallbackFcn(app, @RUNButtonPushed, true);
            app.RUNButton.Text = 'RUN';
            app.TabGroup2 = uitabgroup(app.LeftPanel);
            app.ManipulatorTab = uitab(app.TabGroup2);
            app.ManipulatorTab.Title = 'Manipulator';
            app.NumJointsEditFieldLabel = uilabel(app.ManipulatorTab);
            app.NumJointsEditFieldLabel.Text = 'Num Joints';
            app.NumJointsEditField = uieditfield(app.ManipulatorTab, 'numeric');
            app.NumJointsEditField.ValueChangedFcn = createCallbackFcn(app, @NumJointsEditFieldValueChanged, true);
            app.UITable = uitable(app.ManipulatorTab);
            app.UITable.ColumnName = {'joint type'; 'a'; 'alpha'; 'd'; 'theta'; 'link mass'; 'COMx'; 'COMy'; 'COMz'; 'Ix'; 'Iy'; 'Iz'};
            app.UITable.RowName = {};
            app.SimulationTab = uitab(app.TabGroup2);
            app.SimulationTab.Title = 'Simulation';
            app.DurationsEditFieldLabel = uilabel(app.SimulationTab);
            app.DurationsEditFieldLabel.Text = 'Duration (s)';
            app.DurationsEditField = uieditfield(app.SimulationTab, 'numeric');
            app.TimeStepdtEditFieldLabel = uilabel(app.SimulationTab);
            app.TimeStepdtEditFieldLabel.Text = 'Time Step (dt)';
            app.TimeStepdtEditField = uieditfield(app.SimulationTab, 'numeric');
            app.UITable2 = uitable(app.SimulationTab);
            app.UITable2.ColumnName = {'q0'; 'qd0'; 'external torques'; 'amplitude'; 'frequency'};
            app.UITable2.RowName = {};
            app.ExternalInputDropDownLabel = uilabel(app.SimulationTab);
            app.ExternalInputDropDownLabel.Text = 'External Input';
            app.ExternalInputDropDown = uidropdown(app.SimulationTab);
            app.ExternalInputDropDown.Items = {'No External Input', 'Constant Torque', 'Sinusoidal Torque'};
            app.ExternalInputDropDown.Value = 'No External Input';
            app.EnvironmentCheckBox = uicheckbox(app.SimulationTab);
            app.EnvironmentCheckBox.Text = 'Environment';
            app.xwallpositionmEditFieldLabel = uilabel(app.SimulationTab);
            app.xwallpositionmEditFieldLabel.Text = 'x wall position [m]';
            app.xwallpositionmEditField = uieditfield(app.SimulationTab, 'numeric');
            app.xwallpositionmEditField.ValueChangedFcn = createCallbackFcn(app, @xwallpositionmEditFieldValueChanged, true);
            app.xwallpositionmEditField.Value = 0.85;
            app.WallstiffnessNmEditFieldLabel = uilabel(app.SimulationTab);
            app.WallstiffnessNmEditFieldLabel.Text = 'Wall stiffness [N/m]';
            app.WallstiffnessNmEditField = uieditfield(app.SimulationTab, 'numeric');
            app.WallstiffnessNmEditField.ValueChangedFcn = createCallbackFcn(app, @WallstiffnessNmEditFieldValueChanged, true);
            app.WallstiffnessNmEditField.Value = 500;
            app.WalldampingNsmEditFieldLabel = uilabel(app.SimulationTab);
            app.WalldampingNsmEditFieldLabel.Text = 'Wall damping [Ns/m]';
            app.WalldampingNsmEditField = uieditfield(app.SimulationTab, 'numeric');
            app.WalldampingNsmEditField.ValueChangedFcn = createCallbackFcn(app, @WalldampingNsmEditFieldValueChanged, true);
            app.WalldampingNsmEditField.Value = 20;
            app.ControlTab = uitab(app.TabGroup2);
            app.ControlTab.Title = 'Control';
            app.TabGroup3 = uitabgroup(app.ControlTab);
            app.SharedTab = uitab(app.TabGroup3);
            app.SharedTab.Title = 'Shared';
            app.ModeDropDownLabel = uilabel(app.SharedTab);
            app.ModeDropDownLabel.Text = 'Mode';
            app.ModeDropDown = uidropdown(app.SharedTab);
            app.ModeDropDown.Items = {'Dynamics', 'Compliance', 'Impedance'};
            app.ModeDropDown.Value = 'Dynamics';
            app.TrajectoryDropDownLabel = uilabel(app.SharedTab);
            app.TrajectoryDropDownLabel.Text = 'Trajectory';
            app.TrajectoryDropDown = uidropdown(app.SharedTab);
            app.TrajectoryDropDown.Items = {'Constant', 'Sinusoidal'};
            app.TrajectoryDropDown.Value = 'Constant';
            app.UITable4 = uitable(app.SharedTab);
            app.UITable4.ColumnName = {'x_des'; 'y_des'; 'z_des'; 'xd_des'; 'yd_des'; 'zd_des'; 'xdd_des'; 'ydd_des'; 'zdd_des'; 'amplitude'; 'frequency'; 'step time'};
            app.UITable4.RowName = {};
            app.DynamicsTab = uitab(app.TabGroup3);
            app.DynamicsTab.Title = 'Dynamics';
            app.ComplianceTab = uitab(app.TabGroup3);
            app.ComplianceTab.Title = 'Compliance';
            app.GravityCompensationCheckBox = uicheckbox(app.ComplianceTab);
            app.GravityCompensationCheckBox.Text = 'Gravity Compensation';
            app.UITable5 = uitable(app.ComplianceTab);
            app.UITable5.ColumnName = {'kP'; 'kD'};
            app.UITable5.RowName = {};
            app.ImpedanceTab = uitab(app.TabGroup3);
            app.ImpedanceTab.Title = 'Impedance';
            app.UITable6 = uitable(app.ImpedanceTab);
            app.UITable6.ColumnName = {'Md'; 'Kd'; 'Kp'};
            app.UITable6.RowName = {};
            app.UseInverseDynamicsCheckBox = uicheckbox(app.ImpedanceTab);
            app.UseInverseDynamicsCheckBox.Text = 'Use Inverse Dynamics';
            app.IncludeGravityCompensationCheckBox = uicheckbox(app.ImpedanceTab);
            app.IncludeGravityCompensationCheckBox.Text = 'Include Gravity Compensation';
            app.IncludeCoriolisCentrifugalCheckBox = uicheckbox(app.ImpedanceTab);
            app.IncludeCoriolisCentrifugalCheckBox.Text = 'Include Coriolis/Centrifugal';
            app.CenterPanel = uipanel(app.GridLayout);
            app.UIAxes8 = uiaxes(app.CenterPanel);
            title(app.UIAxes8, 'Manipulator Animation')
            xlabel(app.UIAxes8, 'X')
            ylabel(app.UIAxes8, 'Y')
            zlabel(app.UIAxes8, 'Z')
            app.RightPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.RightPanel);
            app.JointSpaceTab = uitab(app.TabGroup);
            app.JointSpaceTab.Title = 'Joint Space';
            app.UIAxes = uiaxes(app.JointSpaceTab);
            title(app.UIAxes, 'Joint Position')
            xlabel(app.UIAxes, 'Timestep')
            ylabel(app.UIAxes, 'q')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.JointSpaceTab);
            title(app.UIAxes2, 'Joint Velocity')
            xlabel(app.UIAxes2, 'Timestep')
            ylabel(app.UIAxes2, 'qd')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.JointSpaceTab);
            title(app.UIAxes3, 'Joint Acceleration')
            xlabel(app.UIAxes3, 'Timestep')
            ylabel(app.UIAxes3, 'qdd')
            zlabel(app.UIAxes3, 'Z')
            app.TaskSpaceTab = uitab(app.TabGroup);
            app.TaskSpaceTab.Title = 'Task Space';
            app.UIAxes4 = uiaxes(app.TaskSpaceTab);
            title(app.UIAxes4, 'End-Effector Actual Position')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            zlabel(app.UIAxes4, 'Z')
            app.UIAxes5 = uiaxes(app.TaskSpaceTab);
            title(app.UIAxes5, 'End-Effector Desired Position')
            xlabel(app.UIAxes5, 'X')
            ylabel(app.UIAxes5, 'Y')
            zlabel(app.UIAxes5, 'Z')
            app.ForcesTab = uitab(app.TabGroup);
            app.ForcesTab.Title = 'Forces';
            app.UIAxes6 = uiaxes(app.ForcesTab);
            title(app.UIAxes6, 'Tau')
            xlabel(app.UIAxes6, 'X')
            ylabel(app.UIAxes6, 'Y')
            zlabel(app.UIAxes6, 'Z')
            app.UIAxes7 = uiaxes(app.ForcesTab);
            title(app.UIAxes7, 'Contact Forces')
            xlabel(app.UIAxes7, 'X')
            ylabel(app.UIAxes7, 'Y')
            zlabel(app.UIAxes7, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = main_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end